classdef Gaussian_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        StartGaussianSmoothingButton  matlab.ui.control.Button
        Spinner                       matlab.ui.control.Spinner
        Label                         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartGaussianSmoothingButton
        function StartGaussianSmoothingButtonPushed(app, event)
            [filename,pathname] = uigetfile({'*.*'});
            I = imread(fullfile(pathname,filename));
            value = app.Spinner.Value;
            Iblur = imgaussfilt(I,value);
            figure
            imshow(I)
            title('Original Image')
            figure
            imshow(Iblur)
            title('Smoothed Image, \sigma = '+string(value))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'σ';
            app.Spinner = uispinner(app.UIFigure);
            app.StartGaussianSmoothingButton = uibutton(app.UIFigure, 'push');
            app.StartGaussianSmoothingButton.ButtonPushedFcn = createCallbackFcn(app, @StartGaussianSmoothingButtonPushed, true);
            app.StartGaussianSmoothingButton.Text = 'Start Gaussian Smoothing';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gaussian_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end